classdef CovidCount < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        OptionButtonGroup      matlab.ui.container.ButtonGroup
        DailyButton            matlab.ui.control.RadioButton
        CumulativeButton       matlab.ui.control.RadioButton
        AveragedofDaysSlider   matlab.ui.control.Slider
        AveragedofDaysLabel    matlab.ui.control.Label
        DatatoPlotButtonGroup  matlab.ui.container.ButtonGroup
        BothButton             matlab.ui.control.RadioButton
        DeathsButton           matlab.ui.control.RadioButton
        CasesButton            matlab.ui.control.RadioButton
        StateorRegionListBox   matlab.ui.control.ListBox
        StateorRegionLabel     matlab.ui.control.Label
        CountryListBox         matlab.ui.control.ListBox
        CountryLabel           matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end


    properties (Access = public)
        Global % First hierarchy, containing all countries
        Country % List of countries
        States % List of states
        Dates % dates for X axis
        StartDate
        StopDate
        DateTicks
        selectedCountry
        CovidCases
        DeathCases
        country_index
        country_index_end
        region_index
        titleCountry
        titleRegion
        selectedData
        Cases
        Deaths
        AveragingWindow
        Cumulative
        Daily
        date_label
        date_index
    end

    methods (Access = private)

        function plotData(app)
            if app.Cases & app.Deaths
                cla(app.UIAxes,"reset");
                yyaxis(app.UIAxes,"left");
                bar(app.UIAxes,app.Dates,app.CovidCases);
                ylim(app.UIAxes, [0,1.1*max(app.CovidCases)+1]);
                yyaxis(app.UIAxes,"right")
                plot(app.UIAxes,app.Dates,app.DeathCases);
                ylim(app.UIAxes, [0,1.1*max(app.DeathCases)+1]);
            elseif app.Cases
                cla(app.UIAxes,"reset");
                bar(app.UIAxes,app.Dates,app.CovidCases);
                ylim(app.UIAxes, [0,1.1*max(app.CovidCases)+1]);
            else
                cla(app.UIAxes,"reset");
                plot(app.UIAxes,app.Dates,app.DeathCases);
                ylim(app.UIAxes, [0,1.1*max(app.DeathCases)+1]);
            end

            grid(app.UIAxes,"on");
            if app.Cumulative
                if app.region_index > 1
                    str = sprintf('Cumulative Number of Cases and Deaths in %s, [%d-Day Mean]',app.titleRegion,app.AveragingWindow);
                    title(app.UIAxes,str);
                else
                    str = sprintf('Cumulative Number of Cases and Deaths in %s, [%d-Day Mean]',app.titleCountry,app.AveragingWindow);
                    title(app.UIAxes,str);
                end
            end
            if app.Daily
                if app.region_index > 1
                    str = sprintf('Daily Number of Cases and Deaths in %s, [%d-Day Mean]',app.titleRegion,app.AveragingWindow);
                    title(app.UIAxes,str);
                else
                    str = sprintf('Daily Number of Cases and Deaths in %s, [%d-Day Mean]',app.titleCountry,app.AveragingWindow);
                    title(app.UIAxes,str);
                end
            end
            %xlim(app.UIAxes,[1,375]);
            xticks(app.UIAxes,"manual");
            xticklabels(app.UIAxes,"manual");
            xticks(app.UIAxes,app.Dates(app.date_index));
            xticklabels(app.UIAxes,app.date_label);
            xlim(app.UIAxes,[app.Dates(1) app.Dates(end)]);
        end

        function setDefaults(app)
            % Setting up initial values for widgets
            app.CountryListBox.Items = ["Global" ; app.Global.Country];
            app.CountryListBox.Value = app.CountryListBox.Items(1);
            app.StateorRegionListBox.Items = app.Global.Region(1);
            app.StateorRegionListBox.Value = app.StateorRegionListBox.Items(1);
            app.country_index = 1;
            app.region_index = 1;
            app.AveragingWindow = 1;
            app.CumulativeButton.Value = true;
            app.Cumulative = true;
            app.DailyButton.Value = false;
            app.Daily = false;

            % Computing dates
            dates = app.Dates;
            app.Dates = datetime(app.Dates);
            date_vec = datevec(datenum(dates));
            app.date_index = find(date_vec(:,3) == 1);
            app.date_index = [app.date_index;375];
            app.date_label = char(app.Dates(app.date_index));

            % Computing cases and deaths to be plotted initially
            app.computeData();
        end

        function computeData(app)
            app.titleCountry = app.Country{app.country_index+app.region_index-1}.Country;
            app.titleRegion = app.Country{app.country_index+app.region_index-1}.Region;
            b = length(app.Country{app.country_index+app.region_index-1}.CovidCases);
            if app.Cumulative
                app.CovidCases = app.Country{app.country_index+app.region_index-1}.CovidCases;
                app.DeathCases = app.Country{app.country_index+app.region_index-1}.DeathCases;
            else
                app.CovidCases(1) = app.Country{app.country_index+app.region_index-1}.CovidCases(1);
                app.DeathCases(1) = app.Country{app.country_index+app.region_index-1}.DeathCases(1);
                for a = 2:b
                    app.CovidCases(a) = app.Country{app.country_index+app.region_index-1}.CovidCases(a) - app.Country{app.country_index+app.region_index-1}.CovidCases(a-1);
                    app.DeathCases(a) = app.Country{app.country_index+app.region_index-1}.DeathCases(a) - app.Country{app.country_index+app.region_index-1}.DeathCases(a-1);
                end
                app.CovidCases(app.CovidCases < 0) = nan;
                app.DeathCases(app.DeathCases < 0) = nan;
            end
            for c = [app.AveragingWindow+1:1:b]
                app.CovidCases(c) = mean(app.CovidCases(c-app.AveragingWindow+1:c));
                app.DeathCases(c) = mean(app.DeathCases(c-app.AveragingWindow+1:c));
            end
            for c = [1:1:app.AveragingWindow]
                app.CovidCases(c) = mean(app.CovidCases(1:c));
                app.DeathCases(c) = mean(app.DeathCases(1:c));
            end

            % Plotting data every time new data is calculated
            app.plotData();
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat covid_data; % Loading all data
            num_countries = size(covid_data(2:end,1));
            app.Dates = string(covid_data(1,3:end));
            cnames = unique(string(covid_data(2:end,1)));
            casesAll = covid_data((covid_data(:,2) == ""),3:end);
            rAll = string(covid_data((covid_data(:,2) == ""),2));
            app.Global = Places(cnames,rAll,casesAll);
            for ii = 2:num_countries
                app.Country{ii} = Places(string(covid_data(ii,1)),string(covid_data(ii,2)), ...
                    covid_data(ii,3:end));
            end
            app.Country{1} = Places("Global","All",casesAll);
            app.Country{1}.CovidCases = sum(app.Country{1}.CovidCases);
            app.Country{1}.DeathCases = sum(app.Country{1}.DeathCases);
            app.setDefaults();
            app.DatatoPlotButtonGroupSelectionChanged();
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            % Finding the first and last index of country selected
            counter = 0;
            for i = 1:length(app.Country)
                if isequal(app.CountryListBox.Value,app.Country{i}.Country)
                    counter = counter + 1;
                    app.country_index_end = i;
                end
            end
            app.country_index = app.country_index_end - counter + 1;
            app.StateorRegionListBox.Items = repmat({'All'},1,counter);
            app.StateorRegionListBoxValueChanged();
            % Computing cases and deaths based on the new selections
            app.computeData();
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            % Creating a list of states or region based on the country selected
            l = app.country_index_end - app.country_index + 1;
            for ii = 1:l
                app.StateorRegionListBox.Items(ii) = cellstr(app.Country{app.country_index+ii-1}.Region);
            end
            % Finding index of state or region selected
            for k = 1:l
                if isequal(app.StateorRegionListBox.Value,app.StateorRegionListBox.Items{k})
                    app.region_index = k;
                    break;
                end
            end
            % Computing cases and deaths based on the new selections
            app.computeData();
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            % app.selectedData = app.DatatoPlotButtonGroup.SelectedObject.Value;
            app.Cases = app.CasesButton.Value || app.BothButton.Value;
            app.Deaths = app.DeathsButton.Value || app.BothButton.Value;
            app.plotData();


        end

        % Value changed function: AveragedofDaysSlider
        function AveragedofDaysSliderValueChanged(app, event)
            % Making sure average selection is always an integer
            app.AveragingWindow = round(event.Value);
            app.AveragedofDaysSlider.Value = round(app.AveragedofDaysSlider.Value);

            % Computing cases and deaths based on the average value
            % selected
            app.computeData();

        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.Cumulative = app.CumulativeButton.Value;
            app.Daily = app.DailyButton.Value;
            app.computeData();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CountryLabel = uilabel(app.UIFigure);
            app.CountryLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.Items = {};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.Value = {};
            app.StateorRegionLabel = uilabel(app.UIFigure);
            app.StateorRegionLabel.Text = {'State'; 'or'; 'Region:'};
            app.StateorRegionListBox = uilistbox(app.UIFigure);
            app.StateorRegionListBox.Items = {};
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.StateorRegionListBox.Value = {};
            app.DatatoPlotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.AveragedofDaysLabel = uilabel(app.UIFigure);
            app.AveragedofDaysLabel.Text = {'Averaged'; '# of Days'};
            app.AveragedofDaysSlider = uislider(app.UIFigure);
            app.AveragedofDaysSlider.Limits = [1 15];
            app.AveragedofDaysSlider.MajorTicks = [1 3 5 7 9 11 13 15];
            app.AveragedofDaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragedofDaysSliderValueChanged, true);
            app.AveragedofDaysSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.AveragedofDaysSlider.Value = 1;
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CovidCount

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end